classdef C3DComputation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        C3DCalculationUIFigure  matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        SettingsTab             matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        CalculateButton         matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
        FolderButton            matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ResultsTab              matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        SUBJECTDropDown         matlab.ui.control.DropDown
        SUBJECTDropDownLabel    matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        FiguresPanel            matlab.ui.container.Panel
        MomentPowerButton       matlab.ui.control.Button
        AngleButton             matlab.ui.control.Button
    end

    properties (Access = public)
        Dir
        File
        Subject
        TableData
        Data
        nRows
        GP
        Files         %Data structure for kinematics & kinetics
        plot_type     %Type of plots: angles, moments, powers
        plotobj
    end
    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data 
            x2 = t.Age;
            y2 = t.BloodPressure(:,2);
            plot(app.UIAxes2,x2,y2,'-o');
        end

        function plot_angle(app)
            Labels = {'Trunk Ant/Pst' [0 100 -10 10] 'deg';
            'Trunk LFlx/LExt' [0 100 -10 10] 'deg';
            'Trunk Int/Ext' [0 100 -20 20] 'deg';
            'Pelv Ant/Pst' [0 100 -10 30] 'deg';
            'Pelv Up/Dn' [0 100 -20 20] 'deg';
            'Pelv Int/Ext' [0 100 -30 30] 'deg';
            'Hip Flx/Ext' [0 100 -20 60] 'deg';
            'Hip Add/Abd' [0 100 -20 20] 'deg';
            'Hip Int/Ext' [0 100 -30 30] 'deg';
            'Knee Flx/Ext' [0 100 -20 90] 'deg';
            'Knee Valg/Var' [0 100 -40 20] 'deg';
            'Knee Int/Ext' [0 100 -60 30] 'deg';
            'Ankle Dor/Pla' [0 100 -30 30] 'deg';
            'Foot Int/Ext' [0 100 -40 40] 'deg'};   
            x_axis = 1:100; %Set x-axis
            t = tiledlayout(app.FiguresPanel,3,6);
            title(t,'Kinematics');
%             t.TileSpacing = 'compact';
%             t.Padding = 'compact';
            y_zero = zeros(length(x_axis),1);
            for i = 1:18
                if (i == 12)||(i == 13)||(i == 14)||(i == 15)||(i == 16)
                    continue
                end
                ax(i) = nexttile(t,i);
                plot(ax(i),x_axis,y_zero);
                hold(ax(i),'on');
                grid(ax(i),'on');
                axis(ax(i),'square'); 
            end
            for i = 1:app.nRows
               if isfield(app.Data(i).AngleFit,'left')
                   for c = 1 : length(app.Data(i).AngleFit.left.cycle)
%                         if app.Data(i).LKinem(c) == 1
                            dataA = app.Data(i).AngleFit.left.cycle(c).data();
                            for l = 1:length(dataA)
                                if strncmp(dataA(l).name,'LThorax',7)
                                   idx = 1;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(2);
                                           case 2 % Sagittal
                                           ax_i = ax(1);    
                                           case 3 % Transverse
                                           ax_i = ax(3);
                                       end
                                       plot(ax_i, x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end
                                elseif strncmp(dataA(l).name,'LPelvis',7)
                                    idx = 4;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(8);
                                           case 2 % Sagittal
                                           ax_i = ax(7);    
                                           case 3 % Transverse
                                           ax_i = ax(9);
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end   
                                elseif strncmp(dataA(l).name,'LHip',4)
                                   idx = 7;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(5);
                                           case 2 % Sagittal
                                           ax_i = ax(4);    
                                           case 3 % Transverse
                                           ax_i = ax(6);
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end 
                                elseif strncmp(dataA(l).name,'LKnee',5)
                                   idx = 10;
                                   for p = 1:2
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(11);
                                           case 2 % Sagittal
                                           ax_i = ax(10);    
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end 
                                elseif strncmp(dataA(l).name,'LAnkle',6)
                                   idx = 13;
                                   for p = 1
                                       plot(ax(17),x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(17),ax(17).CurrentPoint(1,1),ax(17).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(17),[Labels{idx,2}])
                                       title(ax(17),Labels{idx,1})
                                       xlabel(ax(17),'% GC')
                                       ylabel(ax(17),Labels{idx,3})
                                   end 
                                elseif strncmp(dataA(l).name,'LFoot',5)
                                   idx = 14;
                                   for p = 3
                                       plot(ax(18),x_axis,dataA(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(18),ax(18).CurrentPoint(1,1),ax(18).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(18),[Labels{idx,2}])
                                       title(ax(18),Labels{idx,1})
                                       xlabel(ax(18),'% GC')
                                       ylabel(ax(18),Labels{idx,3})
                                   end
                                end
                            end
                    end
               end
               if isfield(app.Data(i).AngleFit,'right')
                   for c = 1 : length(app.Data(i).AngleFit.right.cycle)
%                         if app.Data(i).RKinem(c) == 1
                            dataA = app.Data(i).AngleFit.right.cycle(c).data();
                            for l = 1:length(dataA)
                                if strncmp(dataA(l).name,'RThorax',7)
                                   idx = 1;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(2);
                                           case 2 % Sagittal
                                           ax_i = ax(1);    
                                           case 3 % Transverse
                                           ax_i = ax(3);
                                       end
                                       plot(ax_i, x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                   end                                      
                                elseif strncmp(dataA(l).name,'RPelvis',7)
                                    idx = 4;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(8);
                                           case 2 % Sagittal
                                           ax_i = ax(7);    
                                           case 3 % Transverse
                                           ax_i = ax(9);
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end  
                                elseif strncmp(dataA(l).name,'RHip',4)
                                   idx = 7;
                                   for p = 1:3
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(5);
                                           case 2 % Sagittal
                                           ax_i = ax(4);    
                                           case 3 % Transverse
                                           ax_i = ax(6);
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end 
                                elseif strncmp(dataA(l).name,'RKnee',5)
                                   idx = 10;
                                   for p = 1:2
                                       switch p
                                           case 1 %Frontal
                                           ax_i = ax(11);
                                           case 2 % Sagittal
                                           ax_i = ax(10);    
                                       end
                                       plot(ax_i,x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax_i,ax_i.CurrentPoint(1,1),ax_i.CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax_i,[Labels{idx,2}])
                                       title(ax_i,Labels{idx,1})
                                       xlabel(ax_i,'% GC')
                                       ylabel(ax_i,Labels{idx,3})
                                       idx = idx +1;
                                   end 
                                elseif strncmp(dataA(l).name,'RAnkle',6)
                                   idx = 13;
                                   for p = 1
                                       plot(ax(17),x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(17),ax(17).CurrentPoint(1,1),ax(17).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(17),[Labels{idx,2}])
                                       title(ax(17),Labels{idx,1})
                                       xlabel(ax(17),'% GC')
                                       ylabel(ax(17),Labels{idx,3})
                                   end 
                                elseif strncmp(dataA(l).name,'RFoot',5)
                                   idx = 14;
                                   for p = 3
                                       plot(ax(18),x_axis,dataA(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(18),ax(18).CurrentPoint(1,1),ax(18).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(18),[Labels{idx,2}])
                                       title(ax(18),Labels{idx,1})
                                       xlabel(ax(18),'% GC')
                                       ylabel(ax(18),Labels{idx,3})
                                   end
                                end
                            end
                    end
               end
            end
            for i = 1:18
                if (i == 12)||(i == 13)||(i == 14)||(i == 15)||(i == 16)
                    continue
                end
                hold(ax(i),'off')
            end
            app.plotobj = t;
        end
        
        function plot_moment(app)
            Labels = {'Hip Flx/Ext Moment' [0 100 -2 2] 'Nm/kg';
            'Hip Add/Abd Moment' [0 100 -1 1] 'Nm/kg';
            'Hip Int/Ext Moment' [0 100 -1.5 1.5] 'Nm/kg';
            'Hip Power' [0 100 -3 3] 'W/kg';
            'Knee Flx/Ext Moment' [0 100 -1.5 1.5] 'Nm/kg';
            'Knee Valg/Var Moment' [0 100 -1 1] 'Nm/kg';
            'Knee Int/Ext Moment' [0 100 -1.5 1.5] 'Nm/kg';
            'Knee Power' [0 100 -3 3] 'W/kg';
            'Ankle Dor/Pla Moment' [0 100 -1 2] 'Nm/kg';
            'Ankle Power' [0 100 -3 5] 'W/kg'};   
            x_axis = 1:100;
            t = tiledlayout(app.FiguresPanel,3,4,'TileSpacing','Compact');
            title(t,'Kinetics');
            for i = 1:12
                if (i == 10)||(i == 11)
                    continue
                end
                ax(i) = nexttile(t,i);
                hold(ax(i),'on');
                grid(ax(i),'on');
            end
            for i = 1:app.nRows
               if isfield(app.Data(i).MomentFit,'left')
                   for c = 1 : length(app.Data(i).MomentFit.left.cycle)
%                         if app.Data(i).LKin(c) == 1
                            dataM = app.Data(i).MomentFit.left.cycle(c).data();
                            dataP = app.Data(i).PowerFit.left.cycle(c).data();
                            for l = 1:length(dataM)
                                if strncmp(dataM(l).name,'LHip',3)
                                   for p = 1:3 
                                       plot(ax(p), x_axis,dataM(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(p),ax(p).CurrentPoint(1,1),ax(p).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(p),[Labels{p,2}])
                                       title(ax(p),Labels{p,1})
                                       xlabel(ax(p),'% GC')
                                       ylabel(ax(p),Labels{p,3})
                                   end
                                elseif strncmp(dataM(l).name,'LKnee',3)
                                   for p = 1:3
                                       plot(ax(p+4),x_axis,dataM(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(p+4),ax(p+4).CurrentPoint(1,1),ax(p+4).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(p+4),[Labels{p+4,2}])
                                       title(ax(p+4),Labels{p+4,1})
                                       xlabel(ax(p+4),'% GC')
                                       ylabel(ax(p+4),Labels{p+4,3})
                                   end   
                                elseif strncmp(dataM(l).name,'LAnkle',3)
                                   for p = 1
                                       plot(ax(9),x_axis,dataM(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(9),ax(9).CurrentPoint(1,1),ax(9).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(9),[Labels{9,2}])
                                       title(ax(9),Labels{9,1})
                                       xlabel(ax(9),'% GC')
                                       ylabel(ax(9),Labels{9,3})
                                   end 
                                end 
                            end
                            for l = 1:length(dataP)
                                if strncmp(dataP(l).name,'LHip',3)
                                   for p = 3
                                       plot(ax(4),x_axis,dataP(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(4),ax(4).CurrentPoint(1,1),ax(4).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(4),[Labels{4,2}])
                                       title(ax(4),Labels{4,1})
                                       xlabel(ax(4),'% GC')
                                       ylabel(ax(4),Labels{4,3})
                                   end
                                elseif strncmp(dataP(l).name,'LKnee',3)
                                   for p = 3
                                       plot(ax(8),x_axis,dataP(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(8),ax(8).CurrentPoint(1,1),ax(8).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(8),[Labels{8,2}])
                                       title(ax(8),Labels{8,1})
                                       xlabel(ax(8),'% GC')
                                       ylabel(ax(8),Labels{8,3})
                                   end   
                                elseif strncmp(dataP(l).name,'LAnkle',3)
                                   for p = 3
                                       plot(ax(12),x_axis,dataP(l).parameter(p,:),'r','ButtonDownFcn',@(~,~)text(ax(12),ax(12).CurrentPoint(1,1),ax(12).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                       axis(ax(12),[Labels{10,2}])
                                       title(ax(12),Labels{10,1})
                                       xlabel(ax(12),'% GC')
                                       ylabel(ax(12),Labels{10,3})
                                   end 
                                end
                            end
%                         end
                   end
               end
               clear dataM dataP
               if isfield(app.Data(i).MomentFit,'right')
                       for c = 1 : length(app.Data(i).MomentFit.right.cycle)
%                             if app.Data(i).RKin(c) == 1
                                dataM = app.Data(i).MomentFit.right.cycle(c).data();
                                dataP = app.Data(i).PowerFit.right.cycle(c).data();
                                for l = 1:length(dataM)
                                    if strncmp(dataM(l).name,'RHip',3)
                                       for p = 1:3 
                                           plot(ax(p), x_axis,dataM(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(p),ax(p).CurrentPoint(1,1),ax(p).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(p),[Labels{p,2}])
                                           title(ax(p),Labels{p,1})
                                           xlabel(ax(p),'% GC')
                                           ylabel(ax(p),Labels{p,3})
                                       end
                                    elseif strncmp(dataM(l).name,'RKnee',3)
                                       for p = 1:3
                                           plot(ax(p+4),x_axis,dataM(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(p+4),ax(p+4).CurrentPoint(1,1),ax(p+4).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(p+4),[Labels{p+4,2}])
                                           title(ax(p+4),Labels{p+4,1})
                                           xlabel(ax(p+4),'% GC')
                                           ylabel(ax(p+4),Labels{p+4,3})
                                       end   
                                    elseif strncmp(dataM(l).name,'RAnkle',3)
                                       for p = 1
                                           plot(ax(9),x_axis,dataM(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(9),ax(9).CurrentPoint(1,1),ax(9).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(9),[Labels{9,2}])
                                           title(ax(9),Labels{9,1})
                                           xlabel(ax(9),'% GC')
                                           ylabel(ax(9),Labels{9,3})
                                       end 
                                    end 
                                end
                                for l = 1:length(dataP)
                                    if strncmp(dataP(l).name,'RHip',3)
                                       for p = 3
                                           plot(ax(4),x_axis,dataP(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(4),ax(4).CurrentPoint(1,1),ax(4).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(4),[Labels{4,2}])
                                           title(ax(4),Labels{4,1})
                                           xlabel(ax(4),'% GC')
                                           ylabel(ax(4),Labels{4,3})
                                       end
                                    elseif strncmp(dataP(l).name,'RKnee',3)
                                       for p = 3
                                           plot(ax(8),x_axis,dataP(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(8),ax(8).CurrentPoint(1,1),ax(8).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(8),[Labels{8,2}])
                                           title(ax(8),Labels{8,1})
                                           xlabel(ax(8),'% GC')
                                           ylabel(ax(8),Labels{8,3})
                                       end   
                                    elseif strncmp(dataP(l).name,'RAnkle',3)
                                       for p = 3
                                           plot(ax(12),x_axis,dataP(l).parameter(p,:),'g','ButtonDownFcn',@(~,~)text(ax(12),ax(12).CurrentPoint(1,1),ax(12).CurrentPoint(1,2),app.Data(i).name,'FontSize',8));
                                           axis(ax(12),[Labels{10,2}])
                                           title(ax(12),Labels{10,1})
                                           xlabel(ax(12),'% GC')
                                           ylabel(ax(12),Labels{10,3})
                                       end 
                                    end
                                end
%                             end
                       end
                   end
            end
            for i = 1:12
                if (i == 10) || (i == 11)
                    continue
                end
                hold(ax(i),'off')
            end
            app.plotobj = t;
        end
        
        
        function Init_Subjectdata(app,Subject_index)
            %% Subject -> Angle/Moment/Power -> Plane -> Joint ->
            % left/right
            % Thorax
            app.Subject(Subject_index).Angle.Sagittal.Thorax.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Thorax.right = [];
            app.Subject(Subject_index).Angle.Frontal.Thorax.left = [];
            app.Subject(Subject_index).Angle.Frontal.Thorax.right = [];
            app.Subject(Subject_index).Angle.Transverse.Thorax.left = [];
            app.Subject(Subject_index).Angle.Transverse.Thorax.right = [];

            % Pelvis
            app.Subject(Subject_index).Angle.Sagittal.Pelvis.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Pelvis.right = [];
            app.Subject(Subject_index).Angle.Frontal.Pelvis.left = [];
            app.Subject(Subject_index).Angle.Frontal.Pelvis.right = [];
            app.Subject(Subject_index).Angle.Transverse.Pelvis.left = [];
            app.Subject(Subject_index).Angle.Transverse.Pelvis.right = [];

            % Hip
                % Angle
            app.Subject(Subject_index).Angle.Sagittal.Hip.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Hip.right = [];
            app.Subject(Subject_index).Angle.Frontal.Hip.left = [];
            app.Subject(Subject_index).Angle.Frontal.Hip.right = [];
            app.Subject(Subject_index).Angle.Transverse.Hip.left = [];
            app.Subject(Subject_index).Angle.Transverse.Hip.right = [];
                %Moment
            app.Subject(Subject_index).Moment.Sagittal.Hip.left = [];
            app.Subject(Subject_index).Moment.Sagittal.Hip.right = [];
            app.Subject(Subject_index).Moment.Frontal.Hip.left = [];
            app.Subject(Subject_index).Moment.Frontal.Hip.right = [];
            app.Subject(Subject_index).Moment.Transverse.Hip.left = [];
            app.Subject(Subject_index).Moment.Transverse.Hip.right = [];
                %Power
            app.Subject(Subject_index).Power.Hip.left = [];
            app.Subject(Subject_index).Power.Hip.right = [];

            % Knee
                %Angle
            app.Subject(Subject_index).Angle.Sagittal.Knee.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Knee.right = [];
            app.Subject(Subject_index).Angle.Frontal.Knee.left = [];
            app.Subject(Subject_index).Angle.Frontal.Knee.right = [];
            app.Subject(Subject_index).Angle.Transverse.Knee.left = [];
            app.Subject(Subject_index).Angle.Transverse.Knee.right = [];
                %Moment
            app.Subject(Subject_index).Moment.Sagittal.Knee.left = [];
            app.Subject(Subject_index).Moment.Sagittal.Knee.right = [];
            app.Subject(Subject_index).Moment.Frontal.Knee.left = [];
            app.Subject(Subject_index).Moment.Frontal.Knee.right = [];
            app.Subject(Subject_index).Moment.Transverse.Knee.left = [];
            app.Subject(Subject_index).Moment.Transverse.Knee.right = [];   
                % Power
            app.Subject(Subject_index).Power.Knee.left = [];
            app.Subject(Subject_index).Power.Knee.right = [];     

            % Ankle
                %Angle
            app.Subject(Subject_index).Angle.Sagittal.Ankle.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Ankle.right = [];
            app.Subject(Subject_index).Angle.Frontal.Ankle.left = [];
            app.Subject(Subject_index).Angle.Frontal.Ankle.right = [];
            app.Subject(Subject_index).Angle.Transverse.Ankle.left = [];
            app.Subject(Subject_index).Angle.Transverse.Ankle.right = [];
                %Moment
            app.Subject(Subject_index).Moment.Sagittal.Ankle.left = [];
            app.Subject(Subject_index).Moment.Sagittal.Ankle.right = [];
            app.Subject(Subject_index).Moment.Frontal.Ankle.left = [];
            app.Subject(Subject_index).Moment.Frontal.Ankle.right = [];
            app.Subject(Subject_index).Moment.Transverse.Ankle.left = [];
            app.Subject(Subject_index).Moment.Transverse.Ankle.right = [];   
                %Power
            app.Subject(Subject_index).Power.Ankle.left = [];
            app.Subject(Subject_index).Power.Ankle.right = [];   

            % Foot
            app.Subject(Subject_index).Angle.Sagittal.Foot.left = [];
            app.Subject(Subject_index).Angle.Sagittal.Foot.right = [];
            app.Subject(Subject_index).Angle.Frontal.Foot.left = [];
            app.Subject(Subject_index).Angle.Frontal.Foot.right = [];
            app.Subject(Subject_index).Angle.Transverse.Foot.left = [];
            app.Subject(Subject_index).Angle.Transverse.Foot.right = [];
        end
        
        function group_subject_angle(app,Subject_index,i)

            %% Left
            if isfield(app.Data(i).AngleFit,'left')
                for j = 1:length(app.Data(i).AngleFit.left.cycle)
                    % Thorax
                    app.Subject(Subject_index).Angle.Sagittal.Thorax.left = [app.Subject(Subject_index).Angle.Sagittal.Thorax.left app.Data(i).AngleFit.left.cycle(j).data(11).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Thorax.left = [app.Subject(Subject_index).Angle.Frontal.Thorax.left app.Data(i).AngleFit.left.cycle(j).data(11).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Thorax.left = [app.Subject(Subject_index).Angle.Transverse.Thorax.left app.Data(i).AngleFit.left.cycle(j).data(11).parameter(3,:)'];

                    % Pelvis
                    app.Subject(Subject_index).Angle.Sagittal.Pelvis.left = [app.Subject(Subject_index).Angle.Sagittal.Pelvis.left app.Data(i).AngleFit.left.cycle(j).data(10).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Pelvis.left = [app.Subject(Subject_index).Angle.Frontal.Pelvis.left app.Data(i).AngleFit.left.cycle(j).data(10).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Pelvis.left = [app.Subject(Subject_index).Angle.Transverse.Pelvis.left app.Data(i).AngleFit.left.cycle(j).data(10).parameter(3,:)'];

                    % Hip 
                    app.Subject(Subject_index).Angle.Sagittal.Hip.left = [app.Subject(Subject_index).Angle.Sagittal.Hip.left app.Data(i).AngleFit.left.cycle(j).data(1).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Hip.left = [app.Subject(Subject_index).Angle.Frontal.Hip.left app.Data(i).AngleFit.left.cycle(j).data(1).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Hip.left = [app.Subject(Subject_index).Angle.Transverse.Hip.left app.Data(i).AngleFit.left.cycle(j).data(1).parameter(3,:)'];  

                    % Knee
                    app.Subject(Subject_index).Angle.Sagittal.Knee.left = [app.Subject(Subject_index).Angle.Sagittal.Knee.left app.Data(i).AngleFit.left.cycle(j).data(2).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Knee.left = [app.Subject(Subject_index).Angle.Frontal.Knee.left app.Data(i).AngleFit.left.cycle(j).data(2).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Knee.left = [app.Subject(Subject_index).Angle.Transverse.Knee.left app.Data(i).AngleFit.left.cycle(j).data(2).parameter(3,:)'];

                    % Ankle
                    app.Subject(Subject_index).Angle.Sagittal.Ankle.left = [app.Subject(Subject_index).Angle.Sagittal.Ankle.left app.Data(i).AngleFit.left.cycle(j).data(3).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Ankle.left = [app.Subject(Subject_index).Angle.Frontal.Ankle.left app.Data(i).AngleFit.left.cycle(j).data(3).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Ankle.left = [app.Subject(Subject_index).Angle.Transverse.Ankle.left app.Data(i).AngleFit.left.cycle(j).data(3).parameter(3,:)'];

                    % Foot
                    app.Subject(Subject_index).Angle.Sagittal.Foot.left = [app.Subject(Subject_index).Angle.Sagittal.Foot.left app.Data(i).AngleFit.left.cycle(j).data(9).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Foot.left = [app.Subject(Subject_index).Angle.Frontal.Foot.left app.Data(i).AngleFit.left.cycle(j).data(9).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Foot.left = [app.Subject(Subject_index).Angle.Transverse.Foot.left app.Data(i).AngleFit.left.cycle(j).data(9).parameter(3,:)'];
                end
            end
            %% Right
            if isfield(app.Data(i).AngleFit,'right')
                for j = 1:length(app.Data(i).AngleFit.right.cycle)
                    % Thorax
                    app.Subject(Subject_index).Angle.Sagittal.Thorax.right = [app.Subject(Subject_index).Angle.Sagittal.Thorax.right app.Data(i).AngleFit.right.cycle(j).data(11).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Thorax.right = [app.Subject(Subject_index).Angle.Frontal.Thorax.right app.Data(i).AngleFit.right.cycle(j).data(11).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Thorax.right = [app.Subject(Subject_index).Angle.Transverse.Thorax.right app.Data(i).AngleFit.right.cycle(j).data(11).parameter(3,:)'];

                    % Pelvis
                    app.Subject(Subject_index).Angle.Sagittal.Pelvis.right = [app.Subject(Subject_index).Angle.Sagittal.Pelvis.right app.Data(i).AngleFit.right.cycle(j).data(10).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Pelvis.right = [app.Subject(Subject_index).Angle.Frontal.Pelvis.right app.Data(i).AngleFit.right.cycle(j).data(10).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Pelvis.right = [app.Subject(Subject_index).Angle.Transverse.Pelvis.right app.Data(i).AngleFit.right.cycle(j).data(10).parameter(3,:)'];

                    % Hip 
                    app.Subject(Subject_index).Angle.Sagittal.Hip.right = [app.Subject(Subject_index).Angle.Sagittal.Hip.right app.Data(i).AngleFit.right.cycle(j).data(1).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Hip.right = [app.Subject(Subject_index).Angle.Frontal.Hip.right app.Data(i).AngleFit.right.cycle(j).data(1).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Hip.right = [app.Subject(Subject_index).Angle.Transverse.Hip.right app.Data(i).AngleFit.right.cycle(j).data(1).parameter(3,:)'];  

                    % Knee
                    app.Subject(Subject_index).Angle.Sagittal.Knee.right = [app.Subject(Subject_index).Angle.Sagittal.Knee.right app.Data(i).AngleFit.right.cycle(j).data(2).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Knee.right = [app.Subject(Subject_index).Angle.Frontal.Knee.right app.Data(i).AngleFit.right.cycle(j).data(2).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Knee.right = [app.Subject(Subject_index).Angle.Transverse.Knee.right app.Data(i).AngleFit.right.cycle(j).data(2).parameter(3,:)'];

                    % Ankle
                    app.Subject(Subject_index).Angle.Sagittal.Ankle.right = [app.Subject(Subject_index).Angle.Sagittal.Ankle.right app.Data(i).AngleFit.right.cycle(j).data(3).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Ankle.right = [app.Subject(Subject_index).Angle.Frontal.Ankle.right app.Data(i).AngleFit.right.cycle(j).data(3).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Ankle.right = [app.Subject(Subject_index).Angle.Transverse.Ankle.right app.Data(i).AngleFit.right.cycle(j).data(3).parameter(3,:)'];

                    % Foot
                    app.Subject(Subject_index).Angle.Sagittal.Foot.right = [app.Subject(Subject_index).Angle.Sagittal.Foot.right app.Data(i).AngleFit.right.cycle(j).data(9).parameter(1,:)'];
                    app.Subject(Subject_index).Angle.Frontal.Foot.right = [app.Subject(Subject_index).Angle.Frontal.Foot.right app.Data(i).AngleFit.right.cycle(j).data(9).parameter(2,:)'];
                    app.Subject(Subject_index).Angle.Transverse.Foot.right = [app.Subject(Subject_index).Angle.Transverse.Foot.right app.Data(i).AngleFit.right.cycle(j).data(9).parameter(3,:)'];
                end
            end            
        end
        
        function group_subject_moment(app,Subject_index,i)
            %% Left
            if isfield(app.Data(i).MomentFit,'left')
                for j = 1:length(app.Data(i).MomentFit.left.cycle)
                     % Hip 
                    app.Subject(Subject_index).Moment.Sagittal.Hip.left = [app.Subject(Subject_index).Moment.Sagittal.Hip.left app.Data(i).MomentFit.left.cycle(j).data(2).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Hip.left = [app.Subject(Subject_index).Moment.Frontal.Hip.left app.Data(i).MomentFit.left.cycle(j).data(2).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Hip.left = [app.Subject(Subject_index).Moment.Transverse.Hip.left app.Data(i).MomentFit.left.cycle(j).data(2).parameter(3,:)'];  

                    % Knee
                    app.Subject(Subject_index).Moment.Sagittal.Knee.left = [app.Subject(Subject_index).Moment.Sagittal.Knee.left app.Data(i).MomentFit.left.cycle(j).data(3).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Knee.left = [app.Subject(Subject_index).Moment.Frontal.Knee.left app.Data(i).MomentFit.left.cycle(j).data(3).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Knee.left = [app.Subject(Subject_index).Moment.Transverse.Knee.left app.Data(i).MomentFit.left.cycle(j).data(3).parameter(3,:)'];

                    % Ankle
                    app.Subject(Subject_index).Moment.Sagittal.Ankle.left = [app.Subject(Subject_index).Moment.Sagittal.Ankle.left app.Data(i).MomentFit.left.cycle(j).data(4).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Ankle.left = [app.Subject(Subject_index).Moment.Frontal.Ankle.left app.Data(i).MomentFit.left.cycle(j).data(4).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Ankle.left = [app.Subject(Subject_index).Moment.Transverse.Ankle.left app.Data(i).MomentFit.left.cycle(j).data(4).parameter(3,:)'];
                end
            end 
            %% Right
            if isfield(app.Data(i).MomentFit,'right')
                for j = 1:length(app.Data(i).MomentFit.right.cycle)
                     % Hip 
                    app.Subject(Subject_index).Moment.Sagittal.Hip.right = [app.Subject(Subject_index).Moment.Sagittal.Hip.right app.Data(i).MomentFit.right.cycle(j).data(2).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Hip.right = [app.Subject(Subject_index).Moment.Frontal.Hip.right app.Data(i).MomentFit.right.cycle(j).data(2).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Hip.right = [app.Subject(Subject_index).Moment.Transverse.Hip.right app.Data(i).MomentFit.right.cycle(j).data(2).parameter(3,:)'];  

                    % Knee
                    app.Subject(Subject_index).Moment.Sagittal.Knee.right = [app.Subject(Subject_index).Moment.Sagittal.Knee.right app.Data(i).MomentFit.right.cycle(j).data(3).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Knee.right = [app.Subject(Subject_index).Moment.Frontal.Knee.right app.Data(i).MomentFit.right.cycle(j).data(3).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Knee.right = [app.Subject(Subject_index).Moment.Transverse.Knee.right app.Data(i).MomentFit.right.cycle(j).data(3).parameter(3,:)'];

                    % Ankle
                    app.Subject(Subject_index).Moment.Sagittal.Ankle.right = [app.Subject(Subject_index).Moment.Sagittal.Ankle.right app.Data(i).MomentFit.right.cycle(j).data(4).parameter(1,:)'];
                    app.Subject(Subject_index).Moment.Frontal.Ankle.right = [app.Subject(Subject_index).Moment.Frontal.Ankle.right app.Data(i).MomentFit.right.cycle(j).data(4).parameter(2,:)'];
                    app.Subject(Subject_index).Moment.Transverse.Ankle.right = [app.Subject(Subject_index).Moment.Transverse.Ankle.right app.Data(i).MomentFit.right.cycle(j).data(4).parameter(3,:)'];
                end
            end 
        end
        
        function group_subject_power(app,Subject_index,i)
            %% Left
            if isfield(app.Data(i).PowerFit,'left')
                for j = 1:length(app.Data(i).PowerFit.left.cycle)
                     % Hip 
                    app.Subject(Subject_index).Power.Hip.left = [app.Subject(Subject_index).Power.Hip.left app.Data(i).PowerFit.left.cycle(j).data(1).parameter(3,:)'];
                    
                    % Knee
                    app.Subject(Subject_index).Power.Knee.left = [app.Subject(Subject_index).Power.Knee.left app.Data(i).MomentFit.left.cycle(j).data(2).parameter(3,:)'];
                   
                    % Ankle
                    app.Subject(Subject_index).Power.Ankle.left = [app.Subject(Subject_index).Power.Ankle.left app.Data(i).MomentFit.left.cycle(j).data(3).parameter(3,:)'];  
                end
            end
            %% Right
            if isfield(app.Data(i).PowerFit,'right')
                for j = 1:length(app.Data(i).PowerFit.right.cycle)
                     % Hip 
                    app.Subject(Subject_index).Power.Hip.right = [app.Subject(Subject_index).Power.Hip.right app.Data(i).PowerFit.right.cycle(j).data(1).parameter(3,:)'];
                    
                    % Knee
                    app.Subject(Subject_index).Power.Knee.right = [app.Subject(Subject_index).Power.Knee.right app.Data(i).MomentFit.right.cycle(j).data(2).parameter(3,:)'];
                   
                    % Ankle
                    app.Subject(Subject_index).Power.Ankle.right = [app.Subject(Subject_index).Power.Ankle.right app.Data(i).MomentFit.right.cycle(j).data(3).parameter(3,:)'];  
                end
            end            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            app.Data = [];
            app.Files = []; 
            app.plot_type = 'angle';
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            app.Dir = uigetdir(pwd);
            app.C3DCalculationUIFigure.Visible = 'off';
            app.C3DCalculationUIFigure.Visible = 'on';
            app.File = dir(append(app.Dir,'\*.c3d'));
            app.nRows = length(app.File);
            sz = [app.nRows 15];
            varTypes = ["string","string","uint8" ,"uint8","uint8",...
                "uint8","uint8","uint8",...
                "uint8","uint8","uint8",...
                "uint8","uint8","uint8",...
                "string"];
            varNames =  ["Filenames","ID","LKin1","LKin2","LKin3",...
                "RKin1","RKin2","RKin3",...
                "LKinem1","LKinem2","LKinem3",...
                "RKinem1","RKinem2","RKinem3",...
                "AffectedSide"];
            app.UITable.Data = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            cats = categorical(app.UITable.Data.AffectedSide,{'None','Left','Right'});
            app.UITable.Data.AffectedSide = cats;
            %% Evaluation Start
            for i = 1:app.nRows %Loop over all datafiles
                app.UITable.Data.Filenames(i) = app.File(i).name;
                app.UITable.Data.AffectedSide(i) = 'None';
            end
            app.UITable.Enable = 'on';
            app.UITable.Visible = 'on';

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged2(app, event)

           app.TableData = app.UITable.Data;
           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%             app.CalculateButton.Enable = 'off';
            app.GP = [];
            SubjectID = [];
            Subject_index = 0;
            for i = 1:app.nRows
                app.Data(i).LKin(1) = app.UITable.Data.LKin1(i);
                app.Data(i).LKin(2) = app.UITable.Data.LKin2(i);
                app.Data(i).LKin(3) = app.UITable.Data.LKin3(i);

                app.Data(i).LKinem(1) = app.UITable.Data.LKinem1(i);
                app.Data(i).LKinem(2) = app.UITable.Data.LKinem2(i);
                app.Data(i).LKinem(3) = app.UITable.Data.LKinem3(i);

                app.Data(i).RKin(1) = app.UITable.Data.RKin1(i);
                app.Data(i).RKin(2) = app.UITable.Data.RKin2(i);
                app.Data(i).RKin(3) = app.UITable.Data.RKin3(i);

                app.Data(i).RKinem(1) = app.UITable.Data.RKinem1(i);
                app.Data(i).RKinem(2) = app.UITable.Data.RKinem2(i);
                app.Data(i).RKinem(3) = app.UITable.Data.RKinem3(i);
                
                ID = string(app.UITable.Data.ID(i));
                if isempty(SubjectID) || (SubjectID(length(SubjectID)) ~= ID)
                    SubjectID = [SubjectID ID];
                    Subject_index = Subject_index + 1;
                    app.Subject(Subject_index).name = ID;
                    Init_Subjectdata(app,Subject_index);
                end
                app.Data(i).AngleFit = [];      
                app.Data(i).PowerFit = [];      
                app.Data(i).MomentFit = [];     
                app.Data(i).PosWorkFit = [];   
                app.Data(i).NegWorkFit = []; 
                app.Data(i).GP = [];

                fid = fopen(char(app.TableData.Filenames(i)));
                if (fid == -1)
                    continue
                end
                file = char(app.TableData.Filenames(i));  
                app.Data(i).name = erase(file,'.c3d');
                %% Read in Header Data
                [POINTdat,VideoFrameRate,ANALOGdat,AnalogFrameRate,ParameterGroup,HeaderGroup] = readC3D_MoveAbilityLab(file);
                if isempty(POINTdat)
                    continue
                end
                %% Read in Point Data (Angles,Moment,Power,Force)
                [app.Files] = KinematicDataInread(ParameterGroup,POINTdat,app.Files,i,app.TableData.Filenames);
                if isempty(app.Files(i).Data)   %If no information increment file number
                    continue
                end
                %% Read in Events & Cycles
                [events,numevents] = EventDefinition(ParameterGroup,VideoFrameRate);
                [app.Data(i).LStart,app.Data(i).LEnd,app.Data(i).nLcyc,app.Data(i).RStart,app.Data(i).REnd,app.Data(i).nRcyc] = CycleDefinition(numevents,events,HeaderGroup);
                if (app.Data(i).nRcyc == 0 && app.Data(i).nLcyc == 0)  %If no cycles increment file number
                    continue
                end
                %% Spline Fit Kinematics & Kinetics
                %...parameter(1,:) = sagital plane,...parameter(2,:) = frontal plane,...parameter(3,:) = transverse plane
                [app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit] = SplineFit(app,app.Files,app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,i,app.Data(i).LStart,app.Data(i).LEnd,app.Data(i).nLcyc,app.Data(i).RStart,app.Data(i).REnd,app.Data(i).nRcyc);
                %% Spline Fit Positive & Negative Work 
                [app.Data(i).PosWorkFit,app.Data(i).NegWorkFit] = WorkFit(app,app.Data(i).PowerFit,app.Data(i).PosWorkFit,app.Data(i).NegWorkFit,i,app.Data(i).LStart,app.Data(i).LEnd,app.Data(i).nLcyc,app.Data(i).RStart,app.Data(i).REnd,app.Data(i).nRcyc);
%                 %% Separation into Affected and Unaffected Side for Asymmetric Gait
%                 AffectedSide = table2cell(app.TableData(i,'AffectedSide'));
%                 AffectedSide = string(AffectedSide);
%                 [app.Data(i).ASAngleFit,app.Data(i).ASMomentFit,app.Data(i).ASPowerFit,app.Data(i).ASPosWorkFit,app.Data(i).ASNegWorkFit,app.Data(i).ASgait] = ASGait(app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.Data(i).PosWorkFit,app.Data(i).NegWorkFit,AffectedSide,i);
                %% Gait Parameter
                [app.GP] = GPComputation(app,app.Data(i).AngleFit,app.Data(i).MomentFit,app.Data(i).PowerFit,app.GP,app.TableData.Filenames,i);
                %% Grouping angle data coressponding to each subject
                group_subject_angle(app,Subject_index,i);
                %% Grouping moment data coressponding to each subject
                group_subject_moment(app,Subject_index,i);
                %% Grouping power data coressponding to each subject
                group_subject_power(app,Subject_index,i)
            end

            % Create summary profile
            for i = 1:length(Subject_index)
                getSummary(app,i);
%                 app.Subject(i).Angle.Sagittal.Thorax.Summary.Left_mean = mean(app.Subject(i).Angle.Sagittal.Thorax.left,2);
            end
            app.AngleButton.Enable = 'off';
            app.MomentPowerButton.Enable = 'on';
            plot_angle(app);
            %Save GP
            GPXLSX = 'GaitParameter.xlsx'; %Filename for Gait Parameter
            writetable(struct2table(app.GP),GPXLSX)

            FileInputs = 'ReadIn.xlsx';
            writetable(app.UITable.Data,FileInputs)
            app.CalculateButton.Enable = 'on';
        end

        % Button down function: FiguresPanel
        function FiguresPanelButtonDown(app, event)
            coordinates = get(app,'CurrentPoint');
            
        end

        % Button pushed function: MomentPowerButton
        function MomentPowerButtonPushed(app, event)
            app.MomentPowerButton.Enable = 'off';
            app.AngleButton.Enable = 'on';
            plot_moment(app);
        end

        % Button pushed function: AngleButton
        function AngleButtonPushed(app, event)
            app.MomentPowerButton.Enable = 'on';
            app.AngleButton.Enable = 'off';
            plot_angle(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.png', 'Save the file as');
            if isnumeric(filename)
               disp('User pushed cancel. Not saving anything')
            else
               exportgraphics(app.plotobj,fullfile(pathname, filename));
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile('.xlsx');
            data = readtable(fullfile(pathname, filename));
            app.C3DCalculationUIFigure.Visible = 'off';
            app.C3DCalculationUIFigure.Visible = 'on';
            app.UITable.Data = data;
            app.TableData = data;
            app.nRows = height(data);
            ID = 'a';
            Subjectlist = [];
            for i = 1:app.nRows
                if ID ~= string(data.ID(i))
                    ID = string(data.ID(i));
                    Subjectlist = [Subjectlist ID];
                end
            end
            app.SUBJECTDropDown.Items = Subjectlist;
            app.UITable.Enable = 'on';
            app.UITable.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.C3DCalculationUIFigure = uifigure('Visible', 'off');
            app.C3DCalculationUIFigure.Name = 'C3D Calculation';
            app.TabGroup = uitabgroup(app.C3DCalculationUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Data Files'; 'ID'; 'LKin1'; 'LKin2'; 'LKin3'; 'RKin1'; 'RKin2'; 'RKin3'; 'LKinem1'; 'LKinem2'; 'LKinem3'; 'RKinem1'; 'RKinem2'; 'RKinem3'; 'Affected Side'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true true true true true true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged2, true);
            app.FolderButton = uibutton(app.GridLayout2, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder...';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout = uigridlayout(app.ResultsTab);
            app.AngleButton = uibutton(app.GridLayout, 'push');
            app.AngleButton.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.AngleButton.Text = 'Angle';
            app.MomentPowerButton = uibutton(app.GridLayout, 'push');
            app.MomentPowerButton.ButtonPushedFcn = createCallbackFcn(app, @MomentPowerButtonPushed, true);
            app.MomentPowerButton.Text = 'Moment/Power';
            app.FiguresPanel = uipanel(app.GridLayout);
            app.FiguresPanel.Title = 'Figures';
            app.FiguresPanel.ButtonDownFcn = createCallbackFcn(app, @FiguresPanelButtonDown, true);
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SUBJECTDropDownLabel = uilabel(app.GridLayout);
            app.SUBJECTDropDownLabel.Text = 'SUBJECT';
            app.SUBJECTDropDown = uidropdown(app.GridLayout);
            % Show the figure after all components are created
            app.C3DCalculationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C3DComputation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.C3DCalculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.C3DCalculationUIFigure)
        end
    end
end